classdef GUI_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ControlPanelLabel               matlab.ui.control.Label
        StartSimulationButton           matlab.ui.control.StateButton
        ResetButton                     matlab.ui.control.Button
        EStopButton                     matlab.ui.control.Button
        ShutDownButton                  matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        CollisionAvoidancePanel         matlab.ui.container.Panel
        EnableCollisionAvoidanceButton  matlab.ui.control.StateButton
        LightCurtainTriggerButton       matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        outputwindow                    matlab.ui.control.TextArea
        ResumeButton                    matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        TabGroup                        matlab.ui.container.TabGroup
        ManualControlTab                matlab.ui.container.Tab
        LinkAdjustmentPanel             matlab.ui.container.Panel
        Link1SliderLabel                matlab.ui.control.Label
        Link1Slider                     matlab.ui.control.Slider
        Link2SliderLabel                matlab.ui.control.Label
        Link2Slider                     matlab.ui.control.Slider
        Link3SliderLabel                matlab.ui.control.Label
        Link3Slider                     matlab.ui.control.Slider
        Link4SliderLabel                matlab.ui.control.Label
        Link4Slider                     matlab.ui.control.Slider
        Link5SliderLabel                matlab.ui.control.Label
        Link5Slider                     matlab.ui.control.Slider
        Link6SliderLabel                matlab.ui.control.Label
        Link6Slider                     matlab.ui.control.Slider
        Link7SliderLabel                matlab.ui.control.Label
        Link7Slider                     matlab.ui.control.Slider
        JoystickTab                     matlab.ui.container.Tab
        JoystickPanel                   matlab.ui.container.Panel
        XSliderLabel                    matlab.ui.control.Label
        XSlider                         matlab.ui.control.Slider
        YSliderLabel                    matlab.ui.control.Label
        YSlider                         matlab.ui.control.Slider
        ZSliderLabel                    matlab.ui.control.Label
        ZSlider                         matlab.ui.control.Slider
    end

    properties (Access = public)
        kuka; 
        robotStartQ;
        robotUpdateQ;
        projectileMotion;
        control;
        avoidCollisions;
        resolvedMotionRateControl;
        environment; 
        ballPose;
        ballHeight;
        hoopPose;
        ball;
        Link1=0;
        Link2=0;
        Link3=0;
        Link4=0;
        Link5=0;
        Link6=0;
        Link7=0;
        vx=0;
        vy=0;
        vz=0;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.outputwindow.Value = "Initialising........";
            app.Lamp.Color = [0.7 0.7 0.7];
            pause(0.5)
            app.outputwindow.Value = " ";
            try
                app.kuka = LBRIIWAR800(0);
                app.kuka.model.teach();
                app.outputwindow.Value = "Robot loaded successfully.";
            catch 
                app.outputwindow.Value = "Error in initialisng the robot. Restart the program and ensure availability of all files.";
            end    
            
            az = -45; % azimuth, az, is the horizontal rotation about the z-axis as measured in degrees from the negative y-axis. Positive values indicate counterclockwise rotation of the viewpoint
            el = 15; % el is the vertical elevation of the viewpoint in degrees. Positive values of elevation correspond to moving above the object; negative values correspond to moving below the object.
            view(az,el);
            app.hoopPose.position = [5,4,3];
            app.hoopPose.orientation = 0;
            
            app.ballHeight = 0.12065; % 0.12065 = radius of NBA basketball
            
            app.ballPose.position = [0,2,app.ballHeight]; 
            app.ballPose.orientation = 0; % Ball is a sphere and orientation does not matter
            app.environment = Environment(app.kuka,app.ballPose,app.hoopPose);          
            app.ball = app.environment.BuildEnvironment(); 
            app.outputwindow.Value = "Welcome to Hoop Shooter 9000 Control Panel.";
            app.EnableCollisionAvoidanceButtonValueChanged;
        end

        % Value changed function: StartSimulationButton
        function StartSimulationButtonValueChanged(app, event)
             
            app.outputwindow.Value = "Starting simulation.";
            launchAngle = pi/4;
            app.ballPose.position = [0,2,app.ballHeight]; 
            app.robotStartQ = [app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6,app.Link7]; % Reasonable initial guess for ikcon in control
            
            app.kuka.model.animate(app.robotStartQ);
            
            app.projectileMotion = ProjectileMotion(app.kuka,app.hoopPose,launchAngle);
            [projectilePath,intialVelocity] = app.projectileMotion.TrajectoryGenerator();
            app.resolvedMotionRateControl = true; % inverse kinematics (quintic polynomials) = false resolved motion rate control (RMRC) = true
            
            app.control = Control(app.kuka,app.robotStartQ,app.ball,app.hoopPose,launchAngle,projectilePath,intialVelocity, app.avoidCollisions, app.resolvedMotionRateControl);
            app.control.ShootHoop();           
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Lamp.Color = [0.7 0.7 0.7];  
            set(gcf,'color','w');
            app.outputwindow.Value = "Resetting the robot.";
%             delete(app.ballPose);
            app.environment = Environment(app.kuka,app.ballPose,app.hoopPose);
            app.ball = app.environment.BuildEnvironment(); 
            app.ballPose.position = [0,2,app.ballHeight]; 
            
            launchAngle = pi/4;            
            app.robotStartQ = [0,-pi/4,0,0,0,0,0]; % Reasonable initial guess for ikcon in control
            
            app.kuka.model.animate(app.robotStartQ);
                    
            app.projectileMotion = ProjectileMotion(app.kuka,app.hoopPose,launchAngle);
            [projectilePath,intialVelocity] = app.projectileMotion.TrajectoryGenerator();
            app.control = Control(app.kuka,app.robotStartQ,app.ball,app.hoopPose,launchAngle,projectilePath,intialVelocity, app.avoidCollisions, app.resolvedMotionRateControl);
            app.control.ShootHoop();
        end

        % Button pushed function: ShutDownButton
        function ShutDownButtonPushed(app, event)
            clf;
            clear;
            close all;
            clc;
            closereq;
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            app.Lamp.Color = 'green';
            app.outputwindow.Value = "E-stop pushed.";
            set(gcf,'color','r');
            pause;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.EStopButtonPushed;
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            app.Lamp.Color = [0.7 0.7 0.7];
            set(gcf,'color','w');
            app.outputwindow.Value = "Resuming operation.";             
            launchAngle = pi/4;      
            app.robotUpdateQ = app.kuka.model.getpos; 
               
            app.kuka.model.animate(app.robotUpdateQ);
                        
            app.projectileMotion = ProjectileMotion(app.kuka,app.hoopPose,launchAngle);
            [projectilePath,intialVelocity] = app.projectileMotion.TrajectoryGenerator();
            
            app.control = Control(app.kuka,app.robotUpdateQ,app.ball,app.hoopPose,launchAngle,projectilePath,intialVelocity, app.avoidCollisions, app.resolvedMotionRateControl);
            app.control.ShootHoop();
        end

        % Value changed function: EnableCollisionAvoidanceButton
        function EnableCollisionAvoidanceButtonValueChanged(app, event)
            value = app.EnableCollisionAvoidanceButton.Value;
            if value == 0
                app.avoidCollisions = false;
            else
                app.avoidCollisions = true;
            end
        end

        % Button pushed function: LightCurtainTriggerButton
        function LightCurtainTriggerButtonPushed(app, event)
            app.outputwindow.Value = "Person entered the cage. Enabling collsiion detection. Press 'Resume' to continue.";
            set(gcf,'color','c');
            app.avoidCollisions = true;
            pause;
        end

        % Value changing function: Link1Slider
        function Link1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link1 = deg2rad(changingValue);
            app.kuka.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6,app.Link7]);
        end

        % Value changing function: Link2Slider
        function Link2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link2 = deg2rad(changingValue);
            app.kuka.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6,app.Link7]);
        end

        % Value changing function: Link3Slider
        function Link3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link3 = deg2rad(changingValue);
            app.kuka.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6,app.Link7]);
        end

        % Value changing function: Link4Slider
        function Link4SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link4 = deg2rad(changingValue);
            app.kuka.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6,app.Link7]);
        end

        % Value changing function: Link5Slider
        function Link5SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link5 = deg2rad(changingValue);
            app.kuka.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6,app.Link7]);
        end

        % Value changing function: Link6Slider
        function Link6SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link6 = deg2rad(changingValue);
            app.kuka.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6,app.Link7]);
        end

        % Value changing function: Link7Slider
        function Link7SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link7 = deg2rad(changingValue);
            app.kuka.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6,app.Link7]);
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            changingValue = event.Value;
            app.vx=0.3*changingValue;
            dt=0.30;
            dx = [app.vx;app.vy;app.vz;0;0;0];
            q=app.kuka.model.getpos;
            J = app.kuka.model.jacob0(q);
            dq = pinv(J)*dx;
            q = q + dq'*dt;
            app.kuka.model.animate(q);
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValue = event.Value;
            app.vy=0.3*changingValue;
            dt=0.30;
            dx = [app.vx;app.vy;app.vz;0;0;0];
            q=app.kuka.model.getpos;
            J = app.kuka.model.jacob0(q);
            dq = pinv(J)*dx;
            q = q + dq'*dt;
            app.kuka.model.animate(q);
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            changingValue = event.Value;
            app.vz=0.3*changingValue;
            dt=0.30;
            dx = [app.vx;app.vy;app.vz;0;0;0];
            q=app.kuka.model.getpos;
            J = app.kuka.model.jacob0(q);
            dq = pinv(J)*dx;
            q = q + dq'*dt;
            app.kuka.model.animate(q);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel ';
            app.StartSimulationButton = uibutton(app.UIFigure, 'state');
            app.StartSimulationButton.ValueChangedFcn = createCallbackFcn(app, @StartSimulationButtonValueChanged, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E-Stop';
            app.ShutDownButton = uibutton(app.UIFigure, 'push');
            app.ShutDownButton.ButtonPushedFcn = createCallbackFcn(app, @ShutDownButtonPushed, true);
            app.ShutDownButton.Text = 'Shut Down';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'estop.png';
            app.CollisionAvoidancePanel = uipanel(app.UIFigure);
            app.CollisionAvoidancePanel.Title = 'Collision Avoidance';
            app.EnableCollisionAvoidanceButton = uibutton(app.CollisionAvoidancePanel, 'state');
            app.EnableCollisionAvoidanceButton.ValueChangedFcn = createCallbackFcn(app, @EnableCollisionAvoidanceButtonValueChanged, true);
            app.EnableCollisionAvoidanceButton.Text = 'Enable Collision Avoidance';
            app.LightCurtainTriggerButton = uibutton(app.CollisionAvoidancePanel, 'push');
            app.LightCurtainTriggerButton.ButtonPushedFcn = createCallbackFcn(app, @LightCurtainTriggerButtonPushed, true);
            app.LightCurtainTriggerButton.Text = 'Light Curtain Trigger';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'logo.png';
            app.outputwindow = uitextarea(app.UIFigure);
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.Lamp = uilamp(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ManualControlTab = uitab(app.TabGroup);
            app.ManualControlTab.Title = 'Manual Control';
            app.LinkAdjustmentPanel = uipanel(app.ManualControlTab);
            app.LinkAdjustmentPanel.Title = 'Link Adjustment';
            app.Link1SliderLabel = uilabel(app.LinkAdjustmentPanel);
            app.Link1SliderLabel.Text = 'Link 1';
            app.Link1Slider = uislider(app.LinkAdjustmentPanel);
            app.Link1Slider.Limits = [-170 170];
            app.Link1Slider.MajorTicks = [-170 -85 0 85 170];
            app.Link1Slider.ValueChangingFcn = createCallbackFcn(app, @Link1SliderValueChanging, true);
            app.Link2SliderLabel = uilabel(app.LinkAdjustmentPanel);
            app.Link2SliderLabel.Text = 'Link 2';
            app.Link2Slider = uislider(app.LinkAdjustmentPanel);
            app.Link2Slider.Limits = [-120 120];
            app.Link2Slider.MajorTicks = [-120 -60 0 60 120];
            app.Link2Slider.ValueChangingFcn = createCallbackFcn(app, @Link2SliderValueChanging, true);
            app.Link3SliderLabel = uilabel(app.LinkAdjustmentPanel);
            app.Link3SliderLabel.Text = 'Link 3';
            app.Link3Slider = uislider(app.LinkAdjustmentPanel);
            app.Link3Slider.Limits = [-170 170];
            app.Link3Slider.MajorTicks = [-170 -85 0 85 170];
            app.Link3Slider.ValueChangingFcn = createCallbackFcn(app, @Link3SliderValueChanging, true);
            app.Link4SliderLabel = uilabel(app.LinkAdjustmentPanel);
            app.Link4SliderLabel.Text = 'Link 4';
            app.Link4Slider = uislider(app.LinkAdjustmentPanel);
            app.Link4Slider.Limits = [-120 120];
            app.Link4Slider.MajorTicks = [-120 -60 0 60 120];
            app.Link4Slider.ValueChangingFcn = createCallbackFcn(app, @Link4SliderValueChanging, true);
            app.Link5SliderLabel = uilabel(app.LinkAdjustmentPanel);
            app.Link5SliderLabel.Text = 'Link 5';
            app.Link5Slider = uislider(app.LinkAdjustmentPanel);
            app.Link5Slider.Limits = [-170 170];
            app.Link5Slider.MajorTicks = [-170 -85 0 85 170];
            app.Link5Slider.ValueChangingFcn = createCallbackFcn(app, @Link5SliderValueChanging, true);
            app.Link6SliderLabel = uilabel(app.LinkAdjustmentPanel);
            app.Link6SliderLabel.Text = 'Link 6';
            app.Link6Slider = uislider(app.LinkAdjustmentPanel);
            app.Link6Slider.Limits = [-120 120];
            app.Link6Slider.MajorTicks = [-120 -60 0 60 120];
            app.Link6Slider.ValueChangingFcn = createCallbackFcn(app, @Link6SliderValueChanging, true);
            app.Link7SliderLabel = uilabel(app.LinkAdjustmentPanel);
            app.Link7SliderLabel.Text = 'Link 7';
            app.Link7Slider = uislider(app.LinkAdjustmentPanel);
            app.Link7Slider.Limits = [-175 175];
            app.Link7Slider.MajorTicks = [-175 -85 0 85 175];
            app.Link7Slider.ValueChangingFcn = createCallbackFcn(app, @Link7SliderValueChanging, true);
            app.JoystickTab = uitab(app.TabGroup);
            app.JoystickTab.Title = 'Joystick';
            app.JoystickPanel = uipanel(app.JoystickTab);
            app.JoystickPanel.Title = 'Joystick';
            app.XSliderLabel = uilabel(app.JoystickPanel);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.JoystickPanel);
            app.XSlider.Limits = [-5 5];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.YSliderLabel = uilabel(app.JoystickPanel);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.JoystickPanel);
            app.YSlider.Limits = [-5 5];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.ZSliderLabel = uilabel(app.JoystickPanel);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.JoystickPanel);
            app.ZSlider.Limits = [-5 5];
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end